classdef CubeSatPowerModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PowerModelTab                   matlab.ui.container.Tab
        ResultsPanel                    matlab.ui.container.Panel
        UIAxes_Results1                 matlab.ui.control.UIAxes
        UIAxes_Results2                 matlab.ui.control.UIAxes
        InputsPanel                     matlab.ui.container.Panel
        RunAnalysisButton               matlab.ui.control.Button
        SolarConstantWm2EditField       matlab.ui.control.NumericEditField
        SolarConstantWm2EditFieldLabel  matlab.ui.control.Label
        PanelEfficiencyEditField        matlab.ui.control.NumericEditField
        PanelEfficiencyLabel            matlab.ui.control.Label
        InherentDegradationEditField    matlab.ui.control.NumericEditField
        InherentDegradationLabel        matlab.ui.control.Label
        DegradationperYearEditField     matlab.ui.control.NumericEditField
        DegradationperYearLabel         matlab.ui.control.Label
        MissionLifetimeYearsEditField   matlab.ui.control.NumericEditField
        MissionLifetimeYearsEditFieldLabel  matlab.ui.control.Label
        ActivepanelsPanel               matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        PositiveXCheckBox               matlab.ui.control.CheckBox
        NegativeXCheckBox               matlab.ui.control.CheckBox
        PositiveYCheckBox               matlab.ui.control.CheckBox
        NegativeYCheckBox               matlab.ui.control.CheckBox
        PositiveZCheckBox               matlab.ui.control.CheckBox
        NegativeZCheckBox               matlab.ui.control.CheckBox
        AnalysisPanel                   matlab.ui.container.Panel
        RefreshPlotsButton              matlab.ui.control.Button
        OrbitAveragedPlotDropDownLabel  matlab.ui.control.Label
        OrbitAveragedPlotDropDown       matlab.ui.control.DropDown
        PerOrbitPlotDropDownLabel       matlab.ui.control.Label
        PerOrbitPlotDropDown            matlab.ui.control.DropDown
        NumberofOrbitsSliderLabel       matlab.ui.control.Label
        NumberofOrbitsSlider            matlab.ui.control.Slider
        FirstOrbitStartTimeSliderLabel  matlab.ui.control.Label
        FirstOrbitStartTimeSlider       matlab.ui.control.Slider
        SaveResultsButton               matlab.ui.control.Button
        GeometryTab                     matlab.ui.container.Tab
        GeometryPanel                   matlab.ui.container.Panel
        UIAxes_Geometry                 matlab.ui.control.UIAxes
        BodySizeButtonGroup             matlab.ui.container.ButtonGroup
        U1                              matlab.ui.control.RadioButton
        U2                              matlab.ui.control.RadioButton
        U3                              matlab.ui.control.RadioButton
        SwapPanelNormalsButton          matlab.ui.control.Button
        DeployablePanels                matlab.ui.container.Panel
        PositiveXButton                 matlab.ui.control.StateButton
        NegativeXButton                 matlab.ui.control.StateButton
        PositiveYButton                 matlab.ui.control.StateButton
        NegativeYButton                 matlab.ui.control.StateButton
        PanelAngleSliderLabel           matlab.ui.control.Label
        PanelAngleSlider                matlab.ui.control.Slider
        AttitudeTab                     matlab.ui.container.Tab
        AttitudePanel                   matlab.ui.container.Panel
        UIAxes_Attitude                 matlab.ui.control.UIAxes
        AttitudeConfigurationPanel      matlab.ui.container.Panel
        attitudeUpdateButton            matlab.ui.control.Button
        NadirSunPointingPanel           matlab.ui.container.Panel
        BodyConstraintVectorattemptstopointalongvelocityvectorPanel  matlab.ui.container.Panel
        XEditField_2Label               matlab.ui.control.Label
        constraintXEditField            matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        constraintYEditField            matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        constraintZEditField            matlab.ui.control.NumericEditField
        BodyAllignmentVectorpointsatreferencebodyPanel  matlab.ui.container.Panel
        XEditFieldLabel                 matlab.ui.control.Label
        bodyXEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        bodyYEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        bodyZEditField                  matlab.ui.control.NumericEditField
        AttitudeModeDropDownLabel       matlab.ui.control.Label
        AttitudeModeDropDown            matlab.ui.control.DropDown
        OrbitPositionSliderLabel        matlab.ui.control.Label
        OrbitPositionSlider             matlab.ui.control.Slider
        NadirReferenceBodyButtonGroup   matlab.ui.container.ButtonGroup
        EarthButton                     matlab.ui.control.RadioButton
        SunButton                       matlab.ui.control.RadioButton
        TumblingPanel                   matlab.ui.container.Panel
        RotationratesradsPanel          matlab.ui.container.Panel
        XEditField_2Label_2             matlab.ui.control.Label
        tumblingXEditField              matlab.ui.control.NumericEditField
        YEditField_2Label_2             matlab.ui.control.Label
        tumblingYEditField              matlab.ui.control.NumericEditField
        ZEditField_2Label_2             matlab.ui.control.Label
        tumblingZEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        % data structures
        orbit       % structure to store orbit data
        panel       % cell structure to store panel geometry data
        attitude    % structure to store attitude data
        mission     % structure to store mission data
        results     % structure to store analysis results
        
        % geometry variables
        U           % variable to store body size
        depPanels   % array to store which deployable panels are active
        theta       % variable to store panel angle
        normalDir   % variable to store normal direction choice
        
        % attitude variables
        attitudeMode            % variable to store attitude mode
        refBody                 % variable to store reference nadir body (0 = Earth, 1 = Sun)
        bodyAlignmentVector     % variable to store body vector pointing at reference
        bodyConstraintVector    % variable to store body vecrot pointing along satVel
        tumblingRates           % variable fr tumbling rates
        orbitPosition
        
        % tempory stuff
        numPanels
        orbitIndex
    end
    
    
    methods (Access = private)
        % setup the axes to display geometry and attitude
        function fcn_setup_axes(~, thisAxes)
            thisAxes.Color = 'none';
            thisAxes.XColor = 'none';
            thisAxes.YColor = 'none';
            thisAxes.ZColor = 'none';
            thisAxes.XTick = '';
            thisAxes.YTick = '';
            thisAxes.ZTick = '';
            thisAxes.DataAspectRatio = [1 1 1];
            thisAxes.Clipping = 'off';
            hold(thisAxes, 'on')
            thisAxes.Projection = 'perspective';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Tempory
            % Set Orbit Initial Values
            reportFileName = 'GMAT_Report.txt';
            
            
            %% Read Orbit Data
            app.orbit = fcn_read_data(reportFileName);
            
            
            %% Set Up Axes
            % geometry
            fcn_setup_axes(app, app.UIAxes_Geometry)
            app.UIAxes_Geometry.View = [45 45];
            % rotate3d(app.UIAxes_Geometry, 'on')
            
            % attitude
            fcn_setup_axes(app, app.UIAxes_Attitude)
            %             view(app.UIAxes_Attitude, app.orbit.satSunUnit(1,:));
            view(app.UIAxes_Attitude, [1 0 0]);
            % rotate3d(app.UIAxes_Attitude, 'on')
            
            % results
            hold(app.UIAxes_Results1, 'on')
            hold(app.UIAxes_Results2, 'on')
            
            
            %% Set initial geometry
            % set all variables
            % initial U
            switch app.BodySizeButtonGroup.SelectedObject.Text
                case '1U'
                    app.U = 1;
                case '2U'
                    app.U = 2;
                case '3U'
                    app.U = 3;
            end
            % initial depPanels
            app.depPanels(1) = app.PositiveXButton.Value;
            app.depPanels(2) = app.NegativeXButton.Value;
            app.depPanels(3) = app.PositiveYButton.Value;
            app.depPanels(4) = app.NegativeYButton.Value;
            % get initial theta
            app.theta = app.PanelAngleSlider.Value;
            % get initial normal direction
            app.normalDir = 0;
            % update and draw geometry
            fcn_draw_geometry(app, app.UIAxes_Geometry)
            
            
            %% Set initial attitude
            % set correct panel
            app.TumblingPanel.Visible = 0;
            % initial reference body
            app.refBody = 0;
            % initial allignment vector
            app.bodyAlignmentVector = [0 0 1];
            % initial constraint vector
            app.bodyConstraintVector = [1 0 0];
            % initial tumbling rates
            app.tumblingRates = [app.tumblingXEditField.Value app.tumblingYEditField.Value app.tumblingZEditField.Value];
            % set initial orbital position
            app.orbitPosition = 1;
            app.orbitIndex = 1:80;
            % update and draw initial attitude
            fcn_draw_attitude(app, app.UIAxes_Attitude)
        end

        % Selection changed function: BodySizeButtonGroup
        function BodySizeButtonGroupSelectionChanged(app, event)
            % set body size variable
            switch app.BodySizeButtonGroup.SelectedObject.Text
                case '1U'
                    app.U = 1;
                case '2U'
                    app.U = 2;
                case '3U'
                    app.U = 3;
            end
            
            % update geometry
            fcn_draw_geometry(app, app.UIAxes_Geometry)
            % update attitude
            fcn_draw_attitude(app, app.UIAxes_Attitude)
        end

        % Value changed function: PanelAngleSlider
        function PanelAngleSliderValueChanged(app, event)
            % set all panel angle
            app.theta = app.PanelAngleSlider.Value;
            
            % update geometry
            fcn_draw_geometry(app, app.UIAxes_Geometry)
            % update attitude
            fcn_draw_attitude(app, app.UIAxes_Attitude)
        end

        % Value changed function: PositiveXButton
        function PositiveXButtonValueChanged(app, event)
            % toggle positive X deployable panel
            app.depPanels(1) = app.PositiveXButton.Value;
            
            % update geometry
            fcn_draw_geometry(app, app.UIAxes_Geometry)
            % update attitude
            fcn_draw_attitude(app, app.UIAxes_Attitude)
        end

        % Value changed function: NegativeXButton
        function NegativeXButtonValueChanged(app, event)
            % toggle negative X deployable panel
            app.depPanels(2) = app.NegativeXButton.Value;
            
            % update geometry
            fcn_draw_geometry(app, app.UIAxes_Geometry)
            % update attitude
            fcn_draw_attitude(app, app.UIAxes_Attitude)
        end

        % Value changed function: PositiveYButton
        function PositiveYButtonValueChanged(app, event)
            % toggle positive Y deployable panel
            app.depPanels(3) = app.PositiveYButton.Value;
            
            % update geometry
            fcn_draw_geometry(app, app.UIAxes_Geometry)
            % update attitude
            fcn_draw_attitude(app, app.UIAxes_Attitude)
        end

        % Value changed function: NegativeYButton
        function NegativeYButtonValueChanged(app, event)
            % toggle negative Y deployable panel
            app.depPanels(4) = app.NegativeYButton.Value;
            
            % update geometry
            fcn_draw_geometry(app, app.UIAxes_Geometry)
            % update attitude
            fcn_draw_attitude(app, app.UIAxes_Attitude)
        end

        % Button pushed function: SwapPanelNormalsButton
        function SwapPanelNormalsButtonPushed(app, event)
            % toggle panel normal side
            switch app.normalDir
                case 0
                    app.normalDir = 1;
                case 1
                    app.normalDir = 0;
            end
            
            % update geometry
            fcn_draw_geometry(app, app.UIAxes_Geometry)
            % update attitude
            fcn_draw_attitude(app, app.UIAxes_Attitude)
        end

        % Selection changed function: NadirReferenceBodyButtonGroup
        function NadirReferenceBodyButtonGroupSelectionChanged(app, event)
            switch app.NadirReferenceBodyButtonGroup.SelectedObject.Text
                case 'Earth'
                    app.refBody = 0;
                case 'Sun'
                    app.refBody = 1;
            end
            
            % update attitude
            fcn_draw_attitude(app, app.UIAxes_Attitude)
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            % read mission constants
            app.mission.solarConstant = app.SolarConstantWm2EditField.Value;
            app.mission.efficiency = app.PanelEfficiencyEditField.Value;
            app.mission.inherentDeg = app.InherentDegradationEditField.Value;
            app.mission.degPerYear = app.DegradationperYearEditField.Value;
            app.mission.lifetime = app.MissionLifetimeYearsEditField.Value;
            
            % activations stores if we need a certain panel. The panels we
            % need are selected with the checkbox in  the UI
            activations = [app.PositiveXCheckBox.Value app.NegativeXCheckBox.Value app.PositiveYCheckBox.Value app.NegativeYCheckBox.Value app.PositiveZCheckBox.Value app.NegativeZCheckBox.Value];
            % run power model
            app.results = fcn_power_model(app.mission, app.orbit, app.panel, app.attitude, activations);
            
            
            % plot average power per orbit
            plot(app.UIAxes_Results1, app.results.powerAvgTime, app.results.powerAvg)
            
            
            % plot total power for each panel
            cla(app.UIAxes_Results2)
            app.numPanels = length(app.panel);
            
            % find the start of the per orbit display period
            orbitIndexStart = floor((app.orbit.numSteps * app.FirstOrbitStartTimeSlider.Value)) + 1;
            if orbitIndexStart + (app.results.orbitStepLength * app.NumberofOrbitsSlider.Value) > app.orbit.numSteps
                orbitIndexEnd = app.orbit.numSteps;
            else
                orbitIndexEnd = floor(orbitIndexStart + (app.results.orbitStepLength * app.NumberofOrbitsSlider.Value));
            end
            app.orbitIndex = orbitIndexStart:orbitIndexEnd;
            
            % plot this period
            %             plot(app.UIAxes_Results2, app.orbit.reportTime(app.orbitIndex), app.results.powerTotal(app.orbitIndex), 'k')
            for j = 1:app.numPanels
                if j <= 6
                    plot(app.UIAxes_Results2, app.orbit.reportTime(app.orbitIndex), app.results.powerPanel(app.orbitIndex, j), app.panel{j}.color)
                else
                    plot(app.UIAxes_Results2, app.orbit.reportTime(app.orbitIndex), app.results.powerPanel(app.orbitIndex, j), [app.panel{j}.color, '--'])
                end
            end
        end

        % Button pushed function: attitudeUpdateButton
        function attitudeUpdateButtonPushed(app, event)
            % set body alignment vector
            app.bodyAlignmentVector = [app.bodyXEditField.Value app.bodyYEditField.Value app.bodyZEditField.Value];
            % set body constraint vector
            app.bodyConstraintVector = [app.constraintXEditField.Value app.constraintYEditField.Value app.constraintZEditField.Value];
            % set tumbling rates
            app.tumblingRates = [app.tumblingXEditField.Value app.tumblingYEditField.Value app.tumblingZEditField.Value];
            
            % draw new attitude
            fcn_draw_attitude(app, app.UIAxes_Attitude)
        end

        % Value changed function: AttitudeModeDropDown
        function AttitudeModeDropDownValueChanged(app, event)
            value = app.AttitudeModeDropDown.Value;
            switch value
                case 'Nadir / Sun Pointing'
                    app.NadirSunPointingPanel.Visible = 1;
                    app.TumblingPanel.Visible = 0;
                    app.attitudeMode = 'Nadir';
                case 'Tumbling'
                    app.NadirSunPointingPanel.Visible = 0;
                    app.TumblingPanel.Visible = 1;
                    app.attitudeMode = 'Tumbling';
            end
        end

        % Button pushed function: RefreshPlotsButton
        function RefreshPlotsButtonPushed(app, event)
            % plot average power per orbit
            plot(app.UIAxes_Results1, app.results.powerAvgTime, app.results.powerAvg)
            
            
            % plot total power for each panel
            cla(app.UIAxes_Results2)
            app.numPanels = length(app.panel);
            
            % find the start of the per orbit display period
            orbitIndexStart = floor((app.orbit.numSteps * app.FirstOrbitStartTimeSlider.Value)) + 1;
            if orbitIndexStart + (app.results.orbitStepLength * app.NumberofOrbitsSlider.Value) > app.orbit.numSteps
                orbitIndexEnd = app.orbit.numSteps;
            else
                orbitIndexEnd = floor(orbitIndexStart + (app.results.orbitStepLength * app.NumberofOrbitsSlider.Value));
            end
            app.orbitIndex = orbitIndexStart:orbitIndexEnd;
            
            % plot this period
            %             plot(app.UIAxes_Results2, app.orbit.reportTime(app.orbitIndex), app.results.powerTotal(app.orbitIndex), 'k')
            for j = 1:app.numPanels
                if j <= 6
                    plot(app.UIAxes_Results2, app.orbit.reportTime(app.orbitIndex), app.results.powerPanel(app.orbitIndex, j), app.panel{j}.color)
                else
                    plot(app.UIAxes_Results2, app.orbit.reportTime(app.orbitIndex), app.results.powerPanel(app.orbitIndex, j), [app.panel{j}.color, '--'])
                end
            end
        end

        % Value changing function: OrbitPositionSlider
        function OrbitPositionSliderValueChanging(app, event)
            app.orbitPosition = event.Value;
            
            % draw new attitude
            fcn_draw_attitude(app, app.UIAxes_Attitude)
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            mission = app.mission;
            orbit = app.orbit;
            panel = app.panel;
            attitude = app.attitude;
            results = app.results;
            uisave({'mission', 'orbit', 'panel', 'attitude', 'results'}, 'powerData_name');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PowerModelTab = uitab(app.TabGroup);
            app.PowerModelTab.Title = 'Power Model';
            app.ResultsPanel = uipanel(app.PowerModelTab);
            app.ResultsPanel.Title = 'Results';
            app.UIAxes_Results1 = uiaxes(app.ResultsPanel);
            title(app.UIAxes_Results1, 'Orbit Averaged Plot')
            xlabel(app.UIAxes_Results1, 'X')
            ylabel(app.UIAxes_Results1, 'Y')
            app.UIAxes_Results2 = uiaxes(app.ResultsPanel);
            title(app.UIAxes_Results2, 'Per Orbit Plot')
            xlabel(app.UIAxes_Results2, 'X')
            ylabel(app.UIAxes_Results2, 'Y')
            app.InputsPanel = uipanel(app.PowerModelTab);
            app.InputsPanel.Title = 'Inputs';
            app.RunAnalysisButton = uibutton(app.InputsPanel, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.SolarConstantWm2EditField = uieditfield(app.InputsPanel, 'numeric');
            app.SolarConstantWm2EditField.Value = 1367;
            app.SolarConstantWm2EditFieldLabel = uilabel(app.InputsPanel);
            app.SolarConstantWm2EditFieldLabel.Text = 'Solar Constant [W/m^2]';
            app.PanelEfficiencyEditField = uieditfield(app.InputsPanel, 'numeric');
            app.PanelEfficiencyEditField.Value = 0.27;
            app.PanelEfficiencyLabel = uilabel(app.InputsPanel);
            app.PanelEfficiencyLabel.Text = 'Panel Efficiency [%]';
            app.InherentDegradationEditField = uieditfield(app.InputsPanel, 'numeric');
            app.InherentDegradationEditField.Value = 0.77;
            app.InherentDegradationLabel = uilabel(app.InputsPanel);
            app.InherentDegradationLabel.Text = 'Inherent Degradation [%]';
            app.DegradationperYearEditField = uieditfield(app.InputsPanel, 'numeric');
            app.DegradationperYearEditField.Value = 0.005;
            app.DegradationperYearLabel = uilabel(app.InputsPanel);
            app.DegradationperYearLabel.Text = 'Degradation per Year [%]';
            app.MissionLifetimeYearsEditField = uieditfield(app.InputsPanel, 'numeric');
            app.MissionLifetimeYearsEditField.Value = 2;
            app.MissionLifetimeYearsEditFieldLabel = uilabel(app.InputsPanel);
            app.MissionLifetimeYearsEditFieldLabel.Text = 'Mission Lifetime [Years]';
            app.ActivepanelsPanel = uipanel(app.InputsPanel);
            app.ActivepanelsPanel.Title = 'Active panels';
            app.GridLayout = uigridlayout(app.ActivepanelsPanel);
            app.PositiveXCheckBox = uicheckbox(app.GridLayout);
            app.PositiveXCheckBox.Text = 'Positive X';
            app.NegativeXCheckBox = uicheckbox(app.GridLayout);
            app.NegativeXCheckBox.Text = 'Negative X';
            app.PositiveYCheckBox = uicheckbox(app.GridLayout);
            app.PositiveYCheckBox.Text = 'Positive Y';
            app.NegativeYCheckBox = uicheckbox(app.GridLayout);
            app.NegativeYCheckBox.Text = 'Negative Y';
            app.PositiveZCheckBox = uicheckbox(app.GridLayout);
            app.PositiveZCheckBox.Text = 'Positive Z';
            app.NegativeZCheckBox = uicheckbox(app.GridLayout);
            app.NegativeZCheckBox.Text = 'Negative Z';
            app.AnalysisPanel = uipanel(app.PowerModelTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.RefreshPlotsButton = uibutton(app.AnalysisPanel, 'push');
            app.RefreshPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPlotsButtonPushed, true);
            app.RefreshPlotsButton.Text = 'Refresh Plots';
            app.OrbitAveragedPlotDropDownLabel = uilabel(app.AnalysisPanel);
            app.OrbitAveragedPlotDropDownLabel.Text = 'Orbit Averaged Plot';
            app.OrbitAveragedPlotDropDown = uidropdown(app.AnalysisPanel);
            app.OrbitAveragedPlotDropDown.Items = {'Orbit Averaged Power', 'Option 2', 'Option 3', 'Option 4'};
            app.OrbitAveragedPlotDropDown.Value = 'Orbit Averaged Power';
            app.PerOrbitPlotDropDownLabel = uilabel(app.AnalysisPanel);
            app.PerOrbitPlotDropDownLabel.Text = 'Per Orbit Plot';
            app.PerOrbitPlotDropDown = uidropdown(app.AnalysisPanel);
            app.PerOrbitPlotDropDown.Items = {'Individual Panel Power', 'Option 2', 'Option 3', 'Option 4'};
            app.PerOrbitPlotDropDown.Value = 'Individual Panel Power';
            app.NumberofOrbitsSliderLabel = uilabel(app.AnalysisPanel);
            app.NumberofOrbitsSliderLabel.Text = 'Number of Orbits';
            app.NumberofOrbitsSlider = uislider(app.AnalysisPanel);
            app.NumberofOrbitsSlider.Limits = [0 10];
            app.FirstOrbitStartTimeSliderLabel = uilabel(app.AnalysisPanel);
            app.FirstOrbitStartTimeSliderLabel.Text = 'First Orbit Start Time';
            app.FirstOrbitStartTimeSlider = uislider(app.AnalysisPanel);
            app.FirstOrbitStartTimeSlider.Limits = [0 0.9];
            app.FirstOrbitStartTimeSlider.MajorTicks = [];
            app.FirstOrbitStartTimeSlider.MajorTickLabels = {};
            app.FirstOrbitStartTimeSlider.MinorTicks = [];
            app.SaveResultsButton = uibutton(app.AnalysisPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.GeometryTab = uitab(app.TabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.GeometryPanel = uipanel(app.GeometryTab);
            app.GeometryPanel.Title = 'Geometry';
            app.UIAxes_Geometry = uiaxes(app.GeometryPanel);
            app.BodySizeButtonGroup = uibuttongroup(app.GeometryTab);
            app.BodySizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BodySizeButtonGroupSelectionChanged, true);
            app.BodySizeButtonGroup.Title = 'Body Size';
            app.U1 = uiradiobutton(app.BodySizeButtonGroup);
            app.U1.Text = '1U';
            app.U2 = uiradiobutton(app.BodySizeButtonGroup);
            app.U2.Text = '2U';
            app.U3 = uiradiobutton(app.BodySizeButtonGroup);
            app.U3.Text = '3U';
            app.U3.Value = true;
            app.SwapPanelNormalsButton = uibutton(app.GeometryTab, 'push');
            app.SwapPanelNormalsButton.ButtonPushedFcn = createCallbackFcn(app, @SwapPanelNormalsButtonPushed, true);
            app.SwapPanelNormalsButton.Text = 'Swap Panel Normals';
            app.DeployablePanels = uipanel(app.GeometryTab);
            app.DeployablePanels.Title = 'Deployable Panels';
            app.PositiveXButton = uibutton(app.DeployablePanels, 'state');
            app.PositiveXButton.ValueChangedFcn = createCallbackFcn(app, @PositiveXButtonValueChanged, true);
            app.PositiveXButton.Text = 'Positive X';
            app.NegativeXButton = uibutton(app.DeployablePanels, 'state');
            app.NegativeXButton.ValueChangedFcn = createCallbackFcn(app, @NegativeXButtonValueChanged, true);
            app.NegativeXButton.Text = 'Negative X';
            app.PositiveYButton = uibutton(app.DeployablePanels, 'state');
            app.PositiveYButton.ValueChangedFcn = createCallbackFcn(app, @PositiveYButtonValueChanged, true);
            app.PositiveYButton.Text = 'Positive Y';
            app.NegativeYButton = uibutton(app.DeployablePanels, 'state');
            app.NegativeYButton.ValueChangedFcn = createCallbackFcn(app, @NegativeYButtonValueChanged, true);
            app.NegativeYButton.Text = 'Negative Y';
            app.PanelAngleSliderLabel = uilabel(app.GeometryTab);
            app.PanelAngleSliderLabel.Text = 'Panel Angle';
            app.PanelAngleSlider = uislider(app.GeometryTab);
            app.PanelAngleSlider.Limits = [0 180];
            app.PanelAngleSlider.ValueChangedFcn = createCallbackFcn(app, @PanelAngleSliderValueChanged, true);
            app.PanelAngleSlider.Value = 90;
            app.AttitudeTab = uitab(app.TabGroup);
            app.AttitudeTab.Title = 'Attitude';
            app.AttitudePanel = uipanel(app.AttitudeTab);
            app.AttitudePanel.Title = 'Attitude';
            app.UIAxes_Attitude = uiaxes(app.AttitudePanel);
            app.AttitudeConfigurationPanel = uipanel(app.AttitudeTab);
            app.AttitudeConfigurationPanel.Title = 'Attitude Configuration';
            app.attitudeUpdateButton = uibutton(app.AttitudeConfigurationPanel, 'push');
            app.attitudeUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @attitudeUpdateButtonPushed, true);
            app.attitudeUpdateButton.Text = 'Update Attitude';
            app.NadirSunPointingPanel = uipanel(app.AttitudeConfigurationPanel);
            app.NadirSunPointingPanel.Title = 'Nadir / Sun Pointing';
            app.BodyConstraintVectorattemptstopointalongvelocityvectorPanel = uipanel(app.NadirSunPointingPanel);
            app.BodyConstraintVectorattemptstopointalongvelocityvectorPanel.Title = 'Body Constraint Vector (attempts to point along velocity vector)';
            app.XEditField_2Label = uilabel(app.BodyConstraintVectorattemptstopointalongvelocityvectorPanel);
            app.XEditField_2Label.Text = 'X';
            app.constraintXEditField = uieditfield(app.BodyConstraintVectorattemptstopointalongvelocityvectorPanel, 'numeric');
            app.constraintXEditField.Value = 1;
            app.YEditField_2Label = uilabel(app.BodyConstraintVectorattemptstopointalongvelocityvectorPanel);
            app.YEditField_2Label.Text = 'Y';
            app.constraintYEditField = uieditfield(app.BodyConstraintVectorattemptstopointalongvelocityvectorPanel, 'numeric');
            app.ZEditField_2Label = uilabel(app.BodyConstraintVectorattemptstopointalongvelocityvectorPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.constraintZEditField = uieditfield(app.BodyConstraintVectorattemptstopointalongvelocityvectorPanel, 'numeric');
            app.BodyAllignmentVectorpointsatreferencebodyPanel = uipanel(app.NadirSunPointingPanel);
            app.BodyAllignmentVectorpointsatreferencebodyPanel.Title = 'Body Allignment Vector (points at reference body)';
            app.XEditFieldLabel = uilabel(app.BodyAllignmentVectorpointsatreferencebodyPanel);
            app.XEditFieldLabel.Text = 'X';
            app.bodyXEditField = uieditfield(app.BodyAllignmentVectorpointsatreferencebodyPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.BodyAllignmentVectorpointsatreferencebodyPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.bodyYEditField = uieditfield(app.BodyAllignmentVectorpointsatreferencebodyPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.BodyAllignmentVectorpointsatreferencebodyPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.bodyZEditField = uieditfield(app.BodyAllignmentVectorpointsatreferencebodyPanel, 'numeric');
            app.bodyZEditField.Value = 1;
            app.AttitudeModeDropDownLabel = uilabel(app.AttitudeConfigurationPanel);
            app.AttitudeModeDropDownLabel.Text = 'Attitude Mode';
            app.AttitudeModeDropDown = uidropdown(app.AttitudeConfigurationPanel);
            app.AttitudeModeDropDown.Items = {'Nadir / Sun Pointing', 'Tumbling'};
            app.AttitudeModeDropDown.ValueChangedFcn = createCallbackFcn(app, @AttitudeModeDropDownValueChanged, true);
            app.AttitudeModeDropDown.Value = 'Nadir / Sun Pointing';
            app.OrbitPositionSliderLabel = uilabel(app.AttitudeConfigurationPanel);
            app.OrbitPositionSliderLabel.Text = 'Orbit Position';
            app.OrbitPositionSlider = uislider(app.AttitudeConfigurationPanel);
            app.OrbitPositionSlider.Limits = [1 80];
            app.OrbitPositionSlider.MajorTicks = [];
            app.OrbitPositionSlider.MajorTickLabels = {};
            app.OrbitPositionSlider.ValueChangingFcn = createCallbackFcn(app, @OrbitPositionSliderValueChanging, true);
            app.OrbitPositionSlider.MinorTicks = [];
            app.OrbitPositionSlider.Value = 1;
            app.NadirReferenceBodyButtonGroup = uibuttongroup(app.AttitudeConfigurationPanel);
            app.NadirReferenceBodyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NadirReferenceBodyButtonGroupSelectionChanged, true);
            app.NadirReferenceBodyButtonGroup.Title = 'Nadir Reference Body';
            app.EarthButton = uiradiobutton(app.NadirReferenceBodyButtonGroup);
            app.EarthButton.Text = 'Earth';
            app.EarthButton.Value = true;
            app.SunButton = uiradiobutton(app.NadirReferenceBodyButtonGroup);
            app.SunButton.Text = 'Sun';
            app.TumblingPanel = uipanel(app.AttitudeConfigurationPanel);
            app.TumblingPanel.Title = 'Tumbling';
            app.RotationratesradsPanel = uipanel(app.TumblingPanel);
            app.RotationratesradsPanel.Title = 'Rotation rates (rad/s)';
            app.XEditField_2Label_2 = uilabel(app.RotationratesradsPanel);
            app.XEditField_2Label_2.Text = 'X';
            app.tumblingXEditField = uieditfield(app.RotationratesradsPanel, 'numeric');
            app.tumblingXEditField.Value = 1;
            app.YEditField_2Label_2 = uilabel(app.RotationratesradsPanel);
            app.YEditField_2Label_2.Text = 'Y';
            app.tumblingYEditField = uieditfield(app.RotationratesradsPanel, 'numeric');
            app.ZEditField_2Label_2 = uilabel(app.RotationratesradsPanel);
            app.ZEditField_2Label_2.Text = 'Z';
            app.tumblingZEditField = uieditfield(app.RotationratesradsPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CubeSatPowerModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end